classdef SetSketch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SetSketchUIFigure        matlab.ui.Figure
        OverallGridLayout        matlab.ui.container.GridLayout
        LoadDataGridLayout       matlab.ui.container.GridLayout
        Filepath2EditField       matlab.ui.control.EditField
        Filepath2EditFieldLabel  matlab.ui.control.Label
        Filepath1EditField       matlab.ui.control.EditField
        Filepath1EditFieldLabel  matlab.ui.control.Label
        LoadButton2              matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        BottomGridLayout         matlab.ui.container.GridLayout
        ProcessGridLayout        matlab.ui.container.GridLayout
        FinishedEditField        matlab.ui.control.EditField
        TODOEditField            matlab.ui.control.EditField
        StartButton              matlab.ui.control.Button
        MiddleGridLayout         matlab.ui.container.GridLayout
        ResultsPanel             matlab.ui.container.Panel
        RightGridLayout          matlab.ui.container.GridLayout
        ResultUITable            matlab.ui.control.Table
        ParametersPanel          matlab.ui.container.Panel
        LeftGridLayout           matlab.ui.container.GridLayout
        ResettoDefaultButton     matlab.ui.control.Button
        qEditField               matlab.ui.control.NumericEditField
        qLabel                   matlab.ui.control.Label
        bEditField               matlab.ui.control.NumericEditField
        bLabel                   matlab.ui.control.Label
        aEditField               matlab.ui.control.NumericEditField
        aLabel                   matlab.ui.control.Label
        mLabel                   matlab.ui.control.Label
        mEditField               matlab.ui.control.NumericEditField
        kLabel                   matlab.ui.control.Label
        kSpinner                 matlab.ui.control.Spinner
        SetSketchLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        a;
        b;
    end
    
    methods (Access = private)
        
        function kmer_num = get_totle_kmer_num(app, file_path)
            k = app.kSpinner.Value;
            try
                fid = fopen(file_path);
                tline = fgetl(fid); % ignore the first row
                cnt = 0;
                tline = fgetl(fid);
                while ischar(tline)
                    cnt = cnt + length(tline);
                    tline = fgetl(fid);
                end
                kmer_num = cnt - k + 1;
            catch
                kmer_num = -1;
            end
        end
        
        function [] = update_processing_bar(app, curr, total)
            remained = total - curr;
            if remained > 1
                app.ProcessGridLayout.ColumnWidth = {strcat(num2str(curr), 'x'), strcat(num2str(remained), 'x')};
            else
                app.ProcessGridLayout.ColumnWidth = {'1x', 0};
                app.FinishedEditField.Value = "Registers constructed. Estimating Jaccard similarty ...";
            end
            pause(0.001);
        end
        
        function [] = finished_processing(app)
            app.ProcessGridLayout.ColumnWidth = {0, '1x'};
            app.FinishedEditField.Value = "Constructing registers ...";
        end
        
        function [kmer_int] = kmer_to_int(~, kmer)
        %KMER_TO_INT View A, C, G, T as 0, 1, 01, 11 in base 2. Turn kmer into an
        %integer of 32 bits. The last two bits corresponds to the last base, etc.
        %Assume that the kmer has fewer than 16 bases.
        %Args:
        %   kmer: a character array of sequences of A, T, G, and C
        %Returns:
        %   encoded integer of base 10
        kmer_int = 0;
        for i = 1:length(kmer)
            kmer_int = kmer_int * 4;
            switch kmer(i)
                case 'C'
                    kmer_int = kmer_int + 1;
                case 'G'
                    kmer_int = kmer_int + 2;
                case 'T'
                    kmer_int = kmer_int + 3;
            end
        end
        end

        function [register, k_low, w] = update_register(~, register, k_low, w, a, b, q, m, seed)
        %UPDATE_REGISTER Update the register according to a new sample with its
        %corresponding seed.
        rng(seed);
        x = 0;
        random_shuffle = randperm(m);
        for j = 1:m
            x = x + random('Exponential', 1/a) / (m+1-j);
            if x > b^(-k_low)
                break
            end
            k = max([0, min([q+1, floor(1 - log(x) / log(b))])]);
            if k <= k_low
                break
            end
            i = random_shuffle(j);
            if k > register(i)
                register(i) = k;
                w = w + 1;
                if w >= m
                    k_low = min(register);
                    w = 0;
                end
            end
        end
        end

        function [register, kmer_set] = construct_register(app, file_path, a, b, q, m, k, finished_kmer_num, curr_total, total_kmer_num)
        %CONSTRUCT_REGISTER 
        %Args:
        %   file_path: a character array to the fasqq file
        %   a, b, q, m: hyperparameters
        %Returns:
        %   register values in a 1 * m integer array
        %   a set of kmer strings
        
        register = zeros(1, m); % initializa registers
        k_low = 0;
        w = 0;

        kmer_set = strings([1, curr_total]);
        kmer_set_id = 0;
        
        fid = fopen(file_path);
        tline = fgetl(fid); % ignore the first row
        tline = fgetl(fid);
        base_per_row = length(tline);
        inputs = char(zeros(1, base_per_row + k - 1)); % store the input
        is_first_row = true;
        while ischar(tline)
            curr_length = length(tline);
            inputs(k:k+curr_length-1) = tline;
            if is_first_row
                start = k;
                is_first_row = false;
            else
                start = 1;
            end
            for start_pos = start:curr_length
                kmer_set_id = kmer_set_id + 1;
                kmer = inputs(start_pos:(start_pos+k-1));
                kmer_set(kmer_set_id) = convertCharsToStrings(kmer);
                curr_seed = app.kmer_to_int(kmer);
                [register, k_low, w] = app.update_register(register, k_low, w, a, b, q, m, curr_seed);
            end
            inputs(1:k-1) = inputs(end-k+2:end);
            % read the next line
            tline = fgetl(fid);
            % update the processing bar
            finished_kmer_num = finished_kmer_num + curr_length - start + 1;
            app.update_processing_bar(finished_kmer_num, total_kmer_num);
        end
        fclose(fid);
        kmer_set = union(kmer_set, kmer_set);
        end

        function [Xm_inv] = estimate_n(~, registers, a, b, m)
        %ESTIMATE_N Estimate the cardinality from the registers
        Xm = 0;
        for i = 1:length(registers)
            Xm = Xm + a * b^(-registers(i));
        end
        Xm = log(b) / (1 - 1/b) / m * Xm;
        Xm_inv = 1 / Xm;
        end
        
        function pb = pbfunx(app, x)
            pb = -log(1 - x * (app.b-1)/app.b) / log(app.b);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ProcessGridLayout.ColumnWidth = {0,'1x'};
            app.FinishedEditField.Value = "Constructing registers ...";
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file_name, path_name] = uigetfile('*.*');
            if isequal(file_name,0)
                return
            end
            data_path=fullfile(path_name,file_name);
            app.Filepath1EditField.Value = data_path;
        end

        % Button pushed function: LoadButton2
        function LoadButton2Pushed(app, event)
            [file_name, path_name] = uigetfile('*.*');
            if isequal(file_name,0)
                return
            end
            data_path=fullfile(path_name,file_name);
            app.Filepath2EditField.Value = data_path;
        end

        % Button pushed function: ResettoDefaultButton
        function ResettoDefaultButtonPushed(app, event)
            app.qEditField.Value  = 65534;
            app.aEditField.Value  = 20;
            app.bEditField.Value = 1.001;
            app.kSpinner.Value = 8;
            app.mEditField.Value = 4096;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if isempty(app.Filepath1EditField.Value) || isempty(app.Filepath2EditField.Value)
                errordlg("Please specify filepaths");
                return
            end

            % get parameters
            file_path1 = app.Filepath1EditField.Value;
            file_path2 = app.Filepath2EditField.Value;
            app.a = app.aEditField.Value;
            app.b = app.bEditField.Value;
            q = app.qEditField.Value;
            m = app.mEditField.Value;
            k = app.kSpinner.Value;
            
            % initialize the processing bar
            kmer_num1 = get_totle_kmer_num(app, file_path1);
            if kmer_num1 == -1
                errordlg("Cannot open input file 1");
                return
            end
            kmer_num2 = get_totle_kmer_num(app, file_path2);
            if kmer_num2 == -1
                errordlg("Cannot open input file 2");
                return
            end
            total_kmer_num = kmer_num1 + kmer_num2;
            
            % construct the registers
            try
                [register1, kmer_set1] = app.construct_register(file_path1, app.a, app.b, q, m, k, 0, kmer_num1, total_kmer_num); %u
                [register2, kmer_set2] = app.construct_register(file_path2, app.a, app.b, q, m, k, kmer_num1, kmer_num2, total_kmer_num); %v
                card1_true = length(kmer_set1);
                card2_true = length(kmer_set2);
                union_true = size(union(kmer_set1, kmer_set2), 2);
                intersect_true = size(intersect(kmer_set1, kmer_set2), 2);
                J_true = intersect_true / union_true;
            catch
                errordlg("Register construction failed");
                app.finished_processing();
                return
            end
            
            % estimate the cardinality of the sets of kmers
            n1 = app.estimate_n(register1, app.a, app.b, m); %u
            n2 = app.estimate_n(register2, app.a, app.b, m); %v

            % estimate the Jaccard similarity
            u = n1 / (n1+n2);
            v = n2 / (n1+n2);
            D_plus = sum(register1>register2);
            D_minus = sum(register1<register2);
            D_0 = sum(register1==register2);
            J_max = min([u/v, v/u]);

            J_estimated = fminbnd(@(J)(-1)*(D_plus * log(app.pbfunx(u-v*J)) + D_minus * log(app.pbfunx(v-u*J)) + D_0 * log(1 - app.pbfunx(u-v*J) - app.pbfunx(v-u*J))), 0, J_max);
            union_estimated = (n1 + n2) / (J_estimated+1);
            intersect_estimated = J_estimated / (J_estimated+1) * (n1+n2);

            %display the result
            result_tbl = array2table(...
                [n1, card1_true;
                 n2, card2_true;
                 union_estimated, union_true;
                 intersect_estimated, intersect_true;
                 J_estimated, J_true]);
            result_tbl.Properties.VariableNames = ["Estimated","True"];
            result_tbl.Variable = ["Cardinality 1"; "Cardinality 2"; "Union"; "Intersection"; "Jaccard"];
            result_tbl = result_tbl(:, ["Variable", "Estimated", "True"]);
            app.ResultUITable.Data = result_tbl;

            app.finished_processing();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SetSketchUIFigure = uifigure('Visible', 'off');
            app.SetSketchUIFigure.Name = 'SetSketch';
            app.OverallGridLayout = uigridlayout(app.SetSketchUIFigure);
            app.SetSketchLabel = uilabel(app.OverallGridLayout);
            app.SetSketchLabel.Text = 'SetSketch';
            app.MiddleGridLayout = uigridlayout(app.OverallGridLayout);
            app.ParametersPanel = uipanel(app.MiddleGridLayout);
            app.ParametersPanel.Title = 'Parameters';
            app.LeftGridLayout = uigridlayout(app.ParametersPanel);
            app.kSpinner = uispinner(app.LeftGridLayout);
            app.kSpinner.Limits = [1 16];
            app.kSpinner.RoundFractionalValues = 'on';
            app.kSpinner.ValueDisplayFormat = '%.0f';
            app.kSpinner.Value = 8;
            app.kLabel = uilabel(app.LeftGridLayout);
            app.kLabel.Tooltip = {'the k chosen for kmer'};
            app.kLabel.Text = 'k:';
            app.mEditField = uieditfield(app.LeftGridLayout, 'numeric');
            app.mEditField.Limits = [1 Inf];
            app.mEditField.RoundFractionalValues = 'on';
            app.mEditField.ValueDisplayFormat = '%.0f';
            app.mEditField.Value = 4096;
            app.mLabel = uilabel(app.LeftGridLayout);
            app.mLabel.Tooltip = {'number of registers'};
            app.mLabel.Text = 'm:';
            app.aLabel = uilabel(app.LeftGridLayout);
            app.aLabel.Tooltip = {'exponential parameter controling the distribution of register values'};
            app.aLabel.Text = 'a:';
            app.aEditField = uieditfield(app.LeftGridLayout, 'numeric');
            app.aEditField.Limits = [0.1 Inf];
            app.aEditField.Value = 20;
            app.bLabel = uilabel(app.LeftGridLayout);
            app.bLabel.Tooltip = {'base parameter controling the distribution of register values'};
            app.bLabel.Text = 'b:';
            app.bEditField = uieditfield(app.LeftGridLayout, 'numeric');
            app.bEditField.Limits = [1 Inf];
            app.bEditField.Value = 1.001;
            app.qLabel = uilabel(app.LeftGridLayout);
            app.qLabel.Tooltip = {'register value range: [0, q+1]'};
            app.qLabel.Text = 'q:';
            app.qEditField = uieditfield(app.LeftGridLayout, 'numeric');
            app.qEditField.Limits = [0 Inf];
            app.qEditField.RoundFractionalValues = 'on';
            app.qEditField.ValueDisplayFormat = '%.0f';
            app.qEditField.Value = 65534;
            app.ResettoDefaultButton = uibutton(app.LeftGridLayout, 'push');
            app.ResettoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @ResettoDefaultButtonPushed, true);
            app.ResettoDefaultButton.Text = 'Reset to Default';
            app.ResultsPanel = uipanel(app.MiddleGridLayout);
            app.ResultsPanel.Title = 'Results';
            app.RightGridLayout = uigridlayout(app.ResultsPanel);
            app.ResultUITable = uitable(app.RightGridLayout);
            app.ResultUITable.ColumnName = {'Variable'; 'SetSketch Estimation'; 'True'};
            app.ResultUITable.RowName = {};
            app.BottomGridLayout = uigridlayout(app.OverallGridLayout);
            app.StartButton = uibutton(app.BottomGridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ProcessGridLayout = uigridlayout(app.BottomGridLayout);
            app.TODOEditField = uieditfield(app.ProcessGridLayout, 'text');
            app.TODOEditField.Editable = 'off';
            app.FinishedEditField = uieditfield(app.ProcessGridLayout, 'text');
            app.FinishedEditField.Editable = 'off';
            app.LoadDataGridLayout = uigridlayout(app.OverallGridLayout);
            app.LoadButton = uibutton(app.LoadDataGridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LoadButton2 = uibutton(app.LoadDataGridLayout, 'push');
            app.LoadButton2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton2Pushed, true);
            app.LoadButton2.Text = 'Load';
            app.Filepath1EditFieldLabel = uilabel(app.LoadDataGridLayout);
            app.Filepath1EditFieldLabel.Text = 'Filepath 1:';
            app.Filepath1EditField = uieditfield(app.LoadDataGridLayout, 'text');
            app.Filepath1EditField.Editable = 'off';
            app.Filepath2EditFieldLabel = uilabel(app.LoadDataGridLayout);
            app.Filepath2EditFieldLabel.Text = 'Filepath 2:';
            app.Filepath2EditField = uieditfield(app.LoadDataGridLayout, 'text');
            app.Filepath2EditField.Editable = 'off';
            % Show the figure after all components are created
            app.SetSketchUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SetSketch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SetSketchUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SetSketchUIFigure)
        end
    end
end